classdef oneColour_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        LocalisaitonsEditField       matlab.ui.control.EditField
        LocalisaitonsEditFieldLabel  matlab.ui.control.Label
        ExportButton                 matlab.ui.control.Button
        EllipseCheckBox              matlab.ui.control.CheckBox
        ConvexhullCheckBox           matlab.ui.control.CheckBox
        DotSizeSlider                matlab.ui.control.Slider
        MinimumNieghboursSlider      matlab.ui.control.Slider
        NeighbourhoodSlider          matlab.ui.control.Slider
        DotSizeEditField             matlab.ui.control.NumericEditField
        DotSizeEditFieldLabel        matlab.ui.control.Label
        SelectdataButton             matlab.ui.control.Button
        NeighbourhoodEditField       matlab.ui.control.NumericEditField
        NeighbourhoodEditFieldLabel  matlab.ui.control.Label
        MinimumNeighboursEditField   matlab.ui.control.NumericEditField
        MinimumNeighboursEditFieldLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        yminEditField                matlab.ui.control.NumericEditField
        yminEditFieldLabel           matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ymaxEditField                matlab.ui.control.NumericEditField
        ymaxEditFieldLabel           matlab.ui.control.Label
        xmaxEditField                matlab.ui.control.NumericEditField
        xmaxEditFieldLabel           matlab.ui.control.Label
        xminEditField                matlab.ui.control.NumericEditField
        xminEditFieldLabel           matlab.ui.control.Label
        GoButton                     matlab.ui.control.Button
        Areaum2EditField             matlab.ui.control.NumericEditField
        Areaum2EditFieldLabel        matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % thresholded coordinate data
        precompDist % precomputed distances for DBSCAN
        clusterIndices = 0; % indices calculated in DBSCAN
        hull 
        area = -1;
        ellipse
        hullData
        normalisedRadialDistances = -1 
        lastPath = false;
        rawData
        gone = false; 
        fileName % Description
    end
    
    methods (Access = private)
        
        
        function results = generatePlot(app)
            data = app.data;
            axes = app.UIAxes;
            axes2 = app.UIAxes2;
            clusterIndices = app.clusterIndices;
            convexHullCheckBox = app.ConvexhullCheckBox.Value;
            ellipseCheckBox = app.EllipseCheckBox.Value;
            ellipse = app.ellipse;
            hull = app.hull;
            area = app.area;
            convexHullData = app.hullData;
            radDist = app.normalisedRadialDistances;
         
            
            cla(axes);
            
            if (clusterIndices == 0)
                scatterPlot = scatter(axes, data(:,1), data(:,2), app.DotSizeEditField.Value, 'k');  
            else
                scatterPlot = scatter(axes, data(:,1), data(:,2), app.DotSizeEditField.Value, clusterIndices~=-1);
            end

            set(axes,'NextPlot','add' );
            if (convexHullCheckBox == true)
                plot(axes,convexHullData(hull,1),convexHullData(hull,2));
            end
            if (ellipseCheckBox == true)
                plot(axes, ellipse.verLine(1,:), ellipse.verLine(2,:),'r' );
                plot(axes, ellipse.horzLine(1,:), ellipse.horzLine(2,:),'r' );
                plot(axes, ellipse.rotatedEllipse(1,:), ellipse.rotatedEllipse(2,:),'r' );
            end
            
            if (area >= 0)
                app.Areaum2EditField.Value = area;
            end
            
            % plot histogram
            if (radDist ~= -1)
                numBins =  24;
                [counts, edges] = histcounts(radDist,linspace(0,1.2,numBins+1));
                aVals = linspace(0, ellipse.a, numBins+1);
                bVals = linspace(0, ellipse.b, numBins+1);
                areas = aVals(2:end).*bVals(2:end)*pi - aVals(1:numBins).*bVals(1:numBins)*pi;
                counts = 10^6*counts./areas;
                histogram(axes2,'BinCounts', counts, 'BinEdges', edges);
                writecell({counts;edges},strcat('singleColourOutput\',app.fileName(1:end-4), ' - RadialDistribution density (signal per um^2).csv'));
                writematrix(radDist,strcat('singleColourOutput\',app.fileName(1:end-4),' - radDist.csv'));
                standardisedDensities = counts/max(counts);
                writecell({standardisedDensities;edges}, strcat('singleColourOutput\',app.fileName(1:end-4),' - Normalised RadialDistribution density (signal per um^2).csv'));
            end
        end
        
        function results = DBSCAN(app)
            neighbourhood = app.NeighbourhoodEditField.Value;
            minNeighbours = app.MinimumNeighboursEditField.Value;
            D = app.precompDist;
            
            app.clusterIndices = dbscan(D, neighbourhood, minNeighbours, 'Distance', 'precomputed');
        end
        
        
        function result = convexHull(app)
            data = app.data;
            clusterIndices = app.clusterIndices;
            
            % Eliminate noise
            cleanedData = data(clusterIndices ~=-1,:);
            % Get hull

            [hull, area] = convhull(cleanedData(:,1),cleanedData(:,2));
            
            app.hull = hull;
            app.area = area/10^6;
            app.hullData = cleanedData;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectdataButton
        function selectData(app, event)
            app.clusterIndices = 0;
            if app.lastPath == false
                [file,path] = uigetfile('*.txt');
            else
                [file,path] = uigetfile('*.txt', 'Select a file', app.lastPath);
            end
            app.fileName = file;
            app.lastPath = path;
            rawData = importdata(fullfile(path,file));
            app.rawData = rawData.data;
            app.data = rawData.data;
            
            app.xminEditField.Enable = 'on';
            app.xmaxEditField.Enable = 'on';
            app.yminEditField.Enable = 'on';
            app.ymaxEditField.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.GoButton.Enable = 'on';
            app.DotSizeEditField.Enable = 'on';
            app.DotSizeSlider.Enable = 'on';
            
            rawData = app.rawData;
            axes = app.UIAxes;
            cla(axes);
            colourMap = [0.5 0.5 0.5; 0 0 1];
            colormap(axes,colourMap);
            scatterPlot = scatter(axes, rawData(:,1), rawData(:,2), app.DotSizeEditField.Value);
            axes.XMinorTick = 'on';
            axes.YMinorTick = 'on';
            axes.XGrid = 'on';
            axes.YGrid = 'on';
            axes.XMinorGrid = 'on';
            axes.YMinorGrid = 'on';
            
            app.xminEditField.Value = app.UIAxes.XTick(1);
            app.xmaxEditField.Value = app.UIAxes.XTick(end);
            app.yminEditField.Value = app.UIAxes.YTick(1);
            app.ymaxEditField.Value = app.UIAxes.YTick(end);
        end

        % Callback function
        function dotSize(app, event)
            value = app.DotSizeEditField.Value;
            app.DotSizeSlider.Value = value;          
            app.generatePlot();
        end

        % Callback function
        function generate(app, event)
            app.DBSCAN();
            app.convexHull();
            app.ellipse = generateEllipse(app.hull,app.hullData);
            app.normalisedRadialDistances = radialDist(app.data,app.ellipse);
            app.generatePlot();
        end

        % Value changed function: NeighbourhoodSlider
        function neighbourhoodSliderFunc(app, event)
            value = round(app.NeighbourhoodSlider.Value,0);
            app.NeighbourhoodSlider.Value = value;
            app.NeighbourhoodEditField.Value = value;
            app.generate();
        end

        % Value changed function: MinimumNieghboursSlider
        function minimumNeighboursSliderFunc(app, event)
            value = round(app.MinimumNieghboursSlider.Value,0);
            app.MinimumNieghboursSlider.Value = value;
            app.MinimumNeighboursEditField.Value = value;
            app.generate();
        end

        % Value changed function: MinimumNeighboursEditField
        function minimumNieghboursEditFieldFunc(app, event)
            value = app.MinimumNeighboursEditField.Value;
            app.MinimumNieghboursSlider.Value = value;
            app.generate();
        end

        % Value changed function: NeighbourhoodEditField
        function neighbourhoodEditFieldFunc(app, event)
            value = app.NeighbourhoodEditField.Value;
            app.NeighbourhoodSlider.Value = value;
            app.generate();
        end

        % Value changed function: DotSizeEditField, DotSizeSlider
        function DotSizeSliderValueChanged(app, event)
            value = app.DotSizeSlider.Value;
            app.DotSizeEditField.Value = value;
            if app.gone == true
                app.generatePlot();
            else
                app.threshold();
            end
        end

        % Value changed function: ConvexhullCheckBox, EllipseCheckBox
        function generate2(app, event)
            app.generatePlot();
        end

        % Button pushed function: ExportButton
        function export(app, event)
            % writematrix(app.distanceFromEdgeArray, 'D:\MatlabCode\DBSCAN
            % ui\text.txt') idk why this is here
            % Prep
            
        end

        % Button pushed function: GoButton
        function go(app, event)
            axes = app.UIAxes;
            % DBSCAN preprocessing
            app.precompDist = pdist2(app.data(:,1:2),app.data(:,1:2));
            % plot tresholded data
            app.generate();
            
            app.MinimumNeighboursEditField.Enable = 'on';
            app.MinimumNieghboursSlider.Enable = 'on';
            app.NeighbourhoodEditField.Enable = 'on';
            app.NeighbourhoodSlider.Enable = 'on';
            app.ConvexhullCheckBox.Enable = 'on';
            app.EllipseCheckBox.Enable = 'on';
            app.ExportButton.Enable = 'on';
            
            app.gone = true;
            axes.XGrid = 'off';
            axes.YGrid = 'off';
            axes.XMinorGrid = 'off';
            axes.YMinorGrid = 'off';
        end

        % Value changed function: ThresholdEditField, xmaxEditField, 
        % ...and 3 other components
        function threshold(app, event)
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            thresholdValue = app.ThresholdEditField.Value;
            rawData = app.rawData;
            
            
            % Thresholding
            thresholdIndex = rawData(:,4) >= thresholdValue & rawData(:,1) >= xmin & rawData(:,1) <= xmax & rawData(:,2) >= ymin & rawData(:,2) <= ymax;
            
            data = rawData(thresholdIndex,:);
            app.data = data;
            axes = app.UIAxes;
            cla(axes);

            scatterPlot = scatter(axes, rawData(:,1), rawData(:,2), app.DotSizeEditField.Value, thresholdIndex);
            
            axes.XGrid = 'on';
            axes.YGrid = 'on';
            axes.XMinorGrid = 'on';
            axes.YMinorGrid = 'on';
            
            app.MinimumNeighboursEditField.Enable = 'off';
            app.MinimumNieghboursSlider.Enable = 'off';
            app.NeighbourhoodEditField.Enable = 'off';
            app.NeighbourhoodSlider.Enable = 'off';
            app.ConvexhullCheckBox.Enable = 'off';
            app.EllipseCheckBox.Enable = 'off';
            app.ExportButton.Enable = 'off';
            
            app.gone = false;
            rawSize = size(rawData,1);
            dataSize = size(data,1);
            app.LocalisaitonsEditField.Value = sprintf('%d/%d',dataSize,rawSize);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {553, 553};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DBSCAN';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MinimumNeighboursEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumNeighboursEditFieldLabel.Text = 'Minimum Neighbours';
            app.MinimumNeighboursEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinimumNeighboursEditField.ValueChangedFcn = createCallbackFcn(app, @minimumNieghboursEditFieldFunc, true);
            app.MinimumNeighboursEditField.Enable = 'off';
            app.MinimumNeighboursEditField.Value = 30;
            app.NeighbourhoodEditFieldLabel = uilabel(app.LeftPanel);
            app.NeighbourhoodEditFieldLabel.Text = 'Neighbourhood';
            app.NeighbourhoodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NeighbourhoodEditField.ValueChangedFcn = createCallbackFcn(app, @neighbourhoodEditFieldFunc, true);
            app.NeighbourhoodEditField.Enable = 'off';
            app.NeighbourhoodEditField.Value = 400;
            app.SelectdataButton = uibutton(app.LeftPanel, 'push');
            app.SelectdataButton.ButtonPushedFcn = createCallbackFcn(app, @selectData, true);
            app.SelectdataButton.Text = 'Select data';
            app.DotSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.DotSizeEditFieldLabel.Text = 'Dot Size';
            app.DotSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DotSizeEditField.ValueChangedFcn = createCallbackFcn(app, @DotSizeSliderValueChanged, true);
            app.DotSizeEditField.Enable = 'off';
            app.DotSizeEditField.Value = 2;
            app.NeighbourhoodSlider = uislider(app.LeftPanel);
            app.NeighbourhoodSlider.Limits = [10 1000];
            app.NeighbourhoodSlider.MajorTicks = [0 200 400 600 800 1000];
            app.NeighbourhoodSlider.ValueChangedFcn = createCallbackFcn(app, @neighbourhoodSliderFunc, true);
            app.NeighbourhoodSlider.MinorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.NeighbourhoodSlider.Enable = 'off';
            app.NeighbourhoodSlider.Value = 400;
            app.MinimumNieghboursSlider = uislider(app.LeftPanel);
            app.MinimumNieghboursSlider.Limits = [10 150];
            app.MinimumNieghboursSlider.MajorTicks = [0 30 60 90 120 150];
            app.MinimumNieghboursSlider.ValueChangedFcn = createCallbackFcn(app, @minimumNeighboursSliderFunc, true);
            app.MinimumNieghboursSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150];
            app.MinimumNieghboursSlider.Enable = 'off';
            app.MinimumNieghboursSlider.Value = 30;
            app.DotSizeSlider = uislider(app.LeftPanel);
            app.DotSizeSlider.Limits = [0.1 4];
            app.DotSizeSlider.MajorTicks = [0 1 2 3 4];
            app.DotSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DotSizeSliderValueChanged, true);
            app.DotSizeSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4];
            app.DotSizeSlider.Enable = 'off';
            app.DotSizeSlider.Value = 2;
            app.ConvexhullCheckBox = uicheckbox(app.LeftPanel);
            app.ConvexhullCheckBox.ValueChangedFcn = createCallbackFcn(app, @generate2, true);
            app.ConvexhullCheckBox.Enable = 'off';
            app.ConvexhullCheckBox.Text = 'Convex hull';
            app.EllipseCheckBox = uicheckbox(app.LeftPanel);
            app.EllipseCheckBox.ValueChangedFcn = createCallbackFcn(app, @generate2, true);
            app.EllipseCheckBox.Enable = 'off';
            app.EllipseCheckBox.Text = 'Ellipse';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @export, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.LocalisaitonsEditFieldLabel = uilabel(app.LeftPanel);
            app.LocalisaitonsEditFieldLabel.Text = 'Localisaitons';
            app.LocalisaitonsEditField = uieditfield(app.LeftPanel, 'text');
            app.LocalisaitonsEditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'Normalised Distance')
            ylabel(app.UIAxes2, 'Frequency')
            app.Areaum2EditFieldLabel = uilabel(app.RightPanel);
            app.Areaum2EditFieldLabel.Text = 'Area (um^2)';
            app.Areaum2EditField = uieditfield(app.RightPanel, 'numeric');
            app.Areaum2EditField.Editable = 'off';
            app.GoButton = uibutton(app.RightPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @go, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.xminEditFieldLabel = uilabel(app.RightPanel);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.RightPanel, 'numeric');
            app.xminEditField.ValueDisplayFormat = '%11.8g';
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.xminEditField.Enable = 'off';
            app.xmaxEditFieldLabel = uilabel(app.RightPanel);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.xmaxEditField.ValueDisplayFormat = '%11.8g';
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.xmaxEditField.Enable = 'off';
            app.ymaxEditFieldLabel = uilabel(app.RightPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.ymaxEditField.ValueDisplayFormat = '%11.8g';
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.ymaxEditField.Enable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.RightPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.RightPanel, 'numeric');
            app.ThresholdEditField.ValueDisplayFormat = '%11.8g';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.ThresholdEditField.Enable = 'off';
            app.yminEditFieldLabel = uilabel(app.RightPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.RightPanel, 'numeric');
            app.yminEditField.ValueDisplayFormat = '%11.8g';
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.yminEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oneColour_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end