classdef twoColour_v8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HistoneThresholdEditField       matlab.ui.control.NumericEditField
        HistoneThresholdEditFieldLabel  matlab.ui.control.Label
        LocalisationsEditField          matlab.ui.control.EditField
        LocalisationsEditFieldLabel     matlab.ui.control.Label
        SelectotherstuffButton          matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        DistanceFromEdgeButton          matlab.ui.control.Button
        EllipseCheckBox                 matlab.ui.control.CheckBox
        ConvexhullCheckBox              matlab.ui.control.CheckBox
        DotSizeSlider                   matlab.ui.control.Slider
        MinimumNieghboursSlider         matlab.ui.control.Slider
        NeighbourhoodSlider             matlab.ui.control.Slider
        DotSizeEditField                matlab.ui.control.NumericEditField
        DotSizeEditFieldLabel           matlab.ui.control.Label
        SelectlaminButton               matlab.ui.control.Button
        NeighbourhoodEditField          matlab.ui.control.NumericEditField
        NeighbourhoodEditFieldLabel     matlab.ui.control.Label
        MinimumNeighboursEditField      matlab.ui.control.NumericEditField
        MinimumNeighboursEditFieldLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel              matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        Areaum2EditField                matlab.ui.control.NumericEditField
        Areaum2EditFieldLabel           matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        laminData % thresholded coordinate laminData
        precompDist % precomputed distances for DBSCAN
        clusterIndices = 0; % indices calculated in DBSCAN
        %threshold = 6000;
        thresholdLamin = 2000;
        thresholdOther = 2000;
        hull 
        area = -1;
        ellipse
        convexHullLaminData = -1
        normalisedRadialDistances = -1 
        distanceFromEdgeArray; 
        otherData = false % thresholded cordinate otherData
        ellipse2 % Description
        gone = false % Description
        lastPath = false % Description
        rawLaminData % Description
        otherDataSelected = false % Description
        thresholdIndex % Description
        inputFileName % Description
        rawOtherData % Description
    end
    
    methods (Access = private)
        
        
        function results = generatePlot(app)
            
            otherData = app.otherData;
            axes = app.UIAxes;
            axes2 = app.UIAxes2;
            clusterIndices = app.clusterIndices;
            convexHullCheckBox = app.ConvexhullCheckBox.Value;
            ellipseCheckBox = app.EllipseCheckBox.Value;
            ellipse = app.ellipse;
            hull = app.hull;
            area = app.area;
            convexHullLaminData = app.convexHullLaminData;
            
         
            cla(axes);

            if (app.gone == false)
                scatterPlot = scatter(axes, app.rawLaminData(:,1), app.rawLaminData(:,2), app.DotSizeEditField.Value, app.thresholdIndex);  
            elseif (app.otherDataSelected == false)
                scatterPlot = scatter(axes, app.laminData(:,1), app.laminData(:,2), app.DotSizeEditField.Value, clusterIndices~=-1);
            else
                cleanedLaminData = app.laminData(clusterIndices ~=-1,:);
                scatterPlot = scatter(axes, cleanedLaminData(:,1), cleanedLaminData(:,2), app.DotSizeEditField.Value, 'yellow');
            end
            if (app.otherDataSelected ~= false)
                app.radialDist();
                scatter(axes, otherData(:,1), otherData(:,2), app.DotSizeEditField.Value,'cyan')
            end
            radDist = app.normalisedRadialDistances;
                
            
            set(axes,'NextPlot','add' );
            if (convexHullCheckBox == true)    
                plot(axes,convexHullLaminData(hull,1),convexHullLaminData(hull,2));
            end
            if (ellipseCheckBox == true)
                plot(axes, ellipse.verLine(1,:), ellipse.verLine(2,:),'r' );
                plot(axes, ellipse.horzLine(1,:), ellipse.horzLine(2,:),'r' );
                plot(axes, ellipse.rotatedEllipse(1,:), ellipse.rotatedEllipse(2,:),'r' );
            end
            
            if (area >= 0)
                app.Areaum2EditField.Value = area;
            end
            
            % plot histogram
            if (radDist ~= -1)
                numBins =  24;
                [counts, edges] = histcounts(radDist,linspace(0,1.2,numBins+1));
                aVals = linspace(0, ellipse.a, numBins+1);
                bVals = linspace(0, ellipse.b, numBins+1);
                areas = aVals(2:end).*bVals(2:end)*pi - aVals(1:numBins).*bVals(1:numBins)*pi;
                counts = 10^6*counts./areas;
                histogram(axes2,'BinCounts', counts, 'BinEdges', edges);
                
                warning('off','all')
                mkdir twoColourOutput
                warning('on','all')                

                writecell({counts;edges},app.fileName('RadialDistribution density (signal per um^2)'));
                writematrix(radDist,app.fileName('radDist'));
                % normalize
                standardisedDensities = counts/max(counts);
                writecell({standardisedDensities;edges},app.fileName('normalised RadialDistribution density(signal per um^2)'));
            end
        end
        
        function results = DBSCAN(app)
            neighbourhood = app.NeighbourhoodEditField.Value;
            minNeighbours = app.MinimumNeighboursEditField.Value;
            D = app.precompDist;
            
            app.clusterIndices = dbscan(D, neighbourhood, minNeighbours, 'Distance', 'precomputed');
        end
        
        
        function result = convexHull(app)
            laminData = app.laminData;
            clusterIndices = app.clusterIndices;
            
            % Eliminate noise
            cleanedLaminData = laminData(clusterIndices ~=-1,:);
            % Get hull

            [hull, area] = convhull(cleanedLaminData(:,1),cleanedLaminData(:,2));
            
            app.hull = hull;
            app.area = area/10^6;
            app.convexHullLaminData = cleanedLaminData;
        end
        
        function results = radialDist(app)
            ellipse = app.ellipse;
            data = app.otherData;
            
            % Compute radial distances
            radDist = ones(length(data),1);
            % stuff we need for radDist
            a = max(ellipse.a,ellipse.b);
            b = min(ellipse.a,ellipse.b);
            phi = ellipse.phi;
            CX = ellipse.X0_in;
            CY = ellipse.Y0_in;
            
            A = a^2*sin(phi)^2 + b^2*cos(phi)^2;
            B = 2*(b^2-a^2  )*sin(phi)*cos(phi);
            C = a^2*cos(phi)^2 + b^2*sin(phi)^2;
            D = -2*A*CX - B*CY;
            E = -B*CX - 2*C*CY;
            F = A*CX^2 + B*CX*CY + C*CY^2 -a^2*b^2;
            
            app.ellipse2 = [A, B, C, D, E, F, CX, CY];
            for i = 1:length(data)
                radDist(i,1) = app.normalisedDistance(data(i,1),data(i,2));
            end
            
            % clean up radial distances
            radDist = real(radDist);
            radDist = radDist(radDist < 1.2);
            app.normalisedRadialDistances = radDist;


        end
        
        
        function result = normalisedDistance(app,PX,PY)
            ellipse = app.ellipse2;
            A = ellipse(1);
            B = ellipse(2);
            C = ellipse(3);
            D = ellipse(4);
            E = ellipse(5);
            F = ellipse(6);
            CX = ellipse(7);
            CY = ellipse(8);
        
        
            % magic solution
            result =  1/((2*A*CX^2 + 2*C*CY^2 + CX*D + CY*E + (B^2*CX^2*PY^2 - 2*B^2*CX*CY*PX*PY + B^2*CY^2*PX^2 + 2*B*CX^2*D*PY - 2*B*CX*CY*D*PX - 2*B*CX*CY*E*PY - 4*F*B*CX*CY - 2*B*CX*D*PX*PY + 2*B*CX*E*PY^2 + 4*F*B*CX*PY + 2*B*CY^2*E*PX + 2*B*CY*D*PX^2 - 2*B*CY*E*PX*PY + 4*F*B*CY*PX - 4*F*B*PX*PY + CX^2*D^2 - 4*A*CX^2*E*PY - 4*A*C*CX^2*PY^2 - 4*A*F*CX^2 + 2*CX*CY*D*E + 4*C*CX*CY*D*PY + 4*A*CX*CY*E*PX + 8*A*C*CX*CY*PX*PY - 2*CX*D^2*PX - 2*CX*D*E*PY - 4*C*CX*D*PY^2 + 4*A*CX*E*PX*PY + 8*A*F*CX*PX - 4*C*CY^2*D*PX + CY^2*E^2 - 4*A*C*CY^2*PX^2 - 4*C*F*CY^2 - 2*CY*D*E*PX + 4*C*CY*D*PX*PY - 2*CY*E^2*PY - 4*A*CY*E*PX^2 + 8*C*F*CY*PY + D^2*PX^2 + 2*D*E*PX*PY + E^2*PY^2 - 4*A*F*PX^2 - 4*C*F*PY^2)^(1/2) - D*PX - E*PY + 2*B*CX*CY - 2*A*CX*PX - B*CX*PY - B*CY*PX - 2*C*CY*PY)/(2*(A*CX^2 + B*CX*CY - 2*A*CX*PX - B*CX*PY + C*CY^2 - B*CY*PX - 2*C*CY*PY + A*PX^2 + B*PX*PY + C*PY^2)));
            
        end
        
        function results = generate(app)
            app.DBSCAN();
            app.convexHull();
            app.ellipse = generateEllipse(app.hull,app.convexHullLaminData);
            
            app.generatePlot();
            dataSize = sum(logical(app.clusterIndices ~= -1));
            rawSize = size(app.clusterIndices,1);
            app.LocalisationsEditField.Value = sprintf('%d/%d',dataSize,rawSize);
        end
        
        function results = fileName(app,input)
            results = "twoColourOutput\" + app.inputFileName(1:end-4) + " - " + input + ".csv";
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectlaminButton
        function selectLaminData(app, event)
            app.otherData = false;
            app.normalisedRadialDistances = -1;
            app.otherDataSelected = false;
            app.clusterIndices = 0;
            if app.lastPath == false
                [file,path] = uigetfile('*.txt', 'Select a file');
            else
                [file,path] = uigetfile('*.txt', 'Select a file', app.lastPath);
            end
            app.UIFigure.Name = "Two Colour Radial Distribution - " + file;
            app.lastPath = path;
            app.clusterIndices = 0;
            rawLaminData = importdata(fullfile(path,file));
            rawLaminData = rawLaminData.data;
            app.laminData = rawLaminData;
            app.rawLaminData = rawLaminData;
            
            app.xminEditField.Enable = 'on';
            app.xmaxEditField.Enable = 'on';
            app.yminEditField.Enable = 'on';
            app.ymaxEditField.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.GoButton.Enable = 'on';
            app.DotSizeEditField.Enable = 'on';
            app.DotSizeSlider.Enable = 'on';
            
            axes = app.UIAxes;
            cla(axes);
            colourMap = [0.5 0.5 0.5; 1 1 0];
            colormap(axes,colourMap);
            scatterPlot = scatter(axes, rawLaminData(:,1), rawLaminData(:,2), app.DotSizeEditField.Value,[1 1 0]);
            axes.XMinorTick = 'on';
            axes.YMinorTick = 'on';
            axes.XGrid = 'on';
            axes.YGrid = 'on';
            axes.XMinorGrid = 'on';
            axes.YMinorGrid = 'on';
            
            app.xminEditField.Value = app.UIAxes.XTick(1);
            app.xmaxEditField.Value = app.UIAxes.XTick(end);
            app.yminEditField.Value = app.UIAxes.YTick(1);
            app.ymaxEditField.Value = app.UIAxes.YTick(end);     
            
            % Disable other buttons if analying multiple data
            app.SelectotherstuffButton.Enable = 'off';
            app.MinimumNeighboursEditField.Enable = 'off';
            app.MinimumNieghboursSlider.Enable = 'off';
            app.NeighbourhoodEditField.Enable = 'off';
            app.NeighbourhoodSlider.Enable = 'off';
            app.ConvexhullCheckBox.Enable = 'off';
            app.EllipseCheckBox.Enable = 'off';
            app.DistanceFromEdgeButton.Enable = 'off';
            app.ExportButton.Enable = 'off';
        end

        % Button pushed function: SelectotherstuffButton
        function selectOtherData(app, event)
            app.otherDataSelected = true;
            if app.lastPath == false
                [file,path] = uigetfile('*.txt');
            else
                [file,path] = uigetfile('*.txt', 'Select a file', app.lastPath);
            end
            app.inputFileName = file;
            rawOtherData = importdata(fullfile(path,file));
            app.rawOtherData = rawOtherData.data;
            

            index = app.rawOtherData(:,4) > app.HistoneThresholdEditField.Value;
            app.otherData = app.rawOtherData(index,:);
            rawSize = size(app.rawOtherData,1);
            dataSize = size(app.otherData,1);
            app.LocalisationsEditField.Value = sprintf('%d/%d',dataSize,rawSize);
            
            app.normalisedRadialDistances = radialDist(app.otherData,app.ellipse);
            app.generatePlot();
                         
            
        end

        % Value changed function: DotSizeEditField
        function dotSize(app, event)
            value = app.DotSizeEditField.Value;
            app.DotSizeSlider.Value = value;          
            app.generatePlot();
        end

        % Value changed function: NeighbourhoodSlider
        function neighbourhoodSliderFunc(app, event)
            value = round(app.NeighbourhoodSlider.Value,0);
            app.NeighbourhoodSlider.Value = value;
            app.NeighbourhoodEditField.Value = value;
            app.generate();
        end

        % Value changed function: MinimumNieghboursSlider
        function minimumNeighboursSliderFunc(app, event)
            value = round(app.MinimumNieghboursSlider.Value,0);
            app.MinimumNieghboursSlider.Value = value;
            app.MinimumNeighboursEditField.Value = value;
            app.generate();
        end

        % Value changed function: MinimumNeighboursEditField
        function minimumNieghboursEditFieldFunc(app, event)
            value = app.MinimumNeighboursEditField.Value;
            app.MinimumNieghboursSlider.Value = value;
            app.generate();
        end

        % Value changed function: NeighbourhoodEditField
        function neighbourhoodEditFieldFunc(app, event)
            value = app.NeighbourhoodEditField.Value;
            app.NeighbourhoodSlider.Value = value;
            app.generate();
        end

        % Value changed function: DotSizeSlider
        function DotSizeSliderValueChanged(app, event)
            value = app.DotSizeSlider.Value;
            app.DotSizeEditField.Value = value;
            if app.gone == true
                app.generatePlot();
            else
                app.threshold();
            end
        end

        % Value changed function: ConvexhullCheckBox, EllipseCheckBox
        function generate2(app, event)
            app.generatePlot();
        end

        % Button pushed function: DistanceFromEdgeButton
        function distanceFromEdge(app, event)
            close all
            laminData = app.laminData;
            clusterIndices = app.clusterIndices;
            data = app.otherData(:,1:2);
            
            % Eliminate noise
            cleanedLaminData = laminData(clusterIndices ~=-1,:);
            
            shp = alphaShape(cleanedLaminData(:,1:2));
            shp.RegionThreshold = 1000000;
            
            shp2 = alphaShape(cleanedLaminData(:,1:2));
            shp2.RegionThreshold = 1000000;
            shp2.HoleThreshold = 100000000;
            
            % Clean data
            isInShape1 = inShape(shp,data);
            isInShape2 = inShape(shp2,data);
            data = data(isInShape2 &~ isInShape1 ,:);
            
            plot(shp);
            hold on
            scatter(data(:,1), data(:,2), app.DotSizeEditField.Value,'cyan')
            hold off
            
            
            figure
            plot(shp)
            [xx yy] = meshgrid(0:10:20000);
            uniformValues = [xx(:) yy(:)];
            
            isInShape1 = inShape(shp,uniformValues);
            isInShape2 = inShape(shp2,uniformValues);
            uniformValues = uniformValues(isInShape2 &~ isInShape1,:);
            hold on;
            scatter(uniformValues(:,1), uniformValues(:,2), app.DotSizeEditField.Value,'cyan')
            hold off;
            
            figure
            [I D] = nearestNeighbor(shp,data(:,1:2));
            realHist = histogram(D)
            hold on
            [I D] = nearestNeighbor(shp,uniformValues);
            simulatedHist = histogram(D)
            
            realHist.Normalization = 'probability';
            simulatedHist.Normalization = 'probability';
            realHist.BinWidth = 50;
            simulatedHist.BinWidth = 50;
            
        end

        % Callback function
        function ThreshEdge(app, event)
             app.ProportionEditField.Value = sum(app.distanceFromEdgeArray < app.ThreshFromEdgeEditField.Value)/length(app.distanceFromEdgeArray);
            
        end

        % Button pushed function: ExportButton
        function export(app, event)
            %% Radial Dist
            ellipse = app.ellipse;
            ellipse2 = app.ellipse2;
            data = app.laminData;
            radDist = ones(length(data),1);
  
            for i = 1:length(data)
                radDist(i,1) = app.normalisedDistance(data(i,1),data(i,2));
            end
            
            % clean up radial distances
            radDist = radDist(radDist < 1.2);

            %% Distance From Edge
            A = ellipse2(1);
            B = ellipse2(2);
            C = ellipse2(3);
            D = ellipse2(4);
            E = ellipse2(5);
            F = ellipse2(6);
            CX = ellipse2(7);
            CY = ellipse2(8);
            
            distanceFromEdgeArray = ones(length(data),1);
            for i = 1:length(data)
                PX = data(i,1);
                PY = data(i,2);
                t = (2*A*CX^2 + 2*C*CY^2 + CX*D + CY*E + (B^2*CX^2*PY^2 - 2*B^2*CX*CY*PX*PY + B^2*CY^2*PX^2 + 2*B*CX^2*D*PY - 2*B*CX*CY*D*PX - 2*B*CX*CY*E*PY - 4*F*B*CX*CY - 2*B*CX*D*PX*PY + 2*B*CX*E*PY^2 + 4*F*B*CX*PY + 2*B*CY^2*E*PX + 2*B*CY*D*PX^2 - 2*B*CY*E*PX*PY + 4*F*B*CY*PX - 4*F*B*PX*PY + CX^2*D^2 - 4*A*CX^2*E*PY - 4*A*C*CX^2*PY^2 - 4*A*F*CX^2 + 2*CX*CY*D*E + 4*C*CX*CY*D*PY + 4*A*CX*CY*E*PX + 8*A*C*CX*CY*PX*PY - 2*CX*D^2*PX - 2*CX*D*E*PY - 4*C*CX*D*PY^2 + 4*A*CX*E*PX*PY + 8*A*F*CX*PX - 4*C*CY^2*D*PX + CY^2*E^2 - 4*A*C*CY^2*PX^2 - 4*C*F*CY^2 - 2*CY*D*E*PX + 4*C*CY*D*PX*PY - 2*CY*E^2*PY - 4*A*CY*E*PX^2 + 8*C*F*CY*PY + D^2*PX^2 + 2*D*E*PX*PY + E^2*PY^2 - 4*A*F*PX^2 - 4*C*F*PY^2)^(1/2) - D*PX - E*PY + 2*B*CX*CY - 2*A*CX*PX - B*CX*PY - B*CY*PX - 2*C*CY*PY)/(2*(A*CX^2 + B*CX*CY - 2*A*CX*PX - B*CX*PY + C*CY^2 - B*CY*PX - 2*C*CY*PY + A*PX^2 + B*PX*PY + C*PY^2));
                distanceFromEdgeArray(i) = (t-1)*pdist([PX,PY;CX,CY]);
            end
            
            numBins = 50;
            distanceFromEdgeArray = distanceFromEdgeArray(distanceFromEdgeArray > 0);
            [counts, edges] = histcounts(distanceFromEdgeArray,linspace(0,1.2,numBins+1));
            aVals = linspace(ellipse2(1), 0, numBins+1);
            bVals = linspace(ellipse2(2), 0, numBins+1);
            areas = -aVals(2:end).*bVals(2:end)*pi + aVals(1:numBins).*bVals(1:numBins)*pi;
            counts = 10^6*counts./areas;
            
            writematrix(counts,strcat('twoColourOutput','\laminCounts_fromedge.csv'));
            writematrix(edges,strcat('twoColourOutput','\laminEdges_fromedge.csv'));
            
            %% export stuff
            numBins =  24;
            [counts, edges] = histcounts(radDist,linspace(0,1.2,numBins+1));
            aVals = linspace(0, ellipse.a, numBins+1);
            bVals = linspace(0, ellipse.b, numBins+1);
            areas = aVals(2:end).*bVals(2:end)*pi - aVals(1:numBins).*bVals(1:numBins)*pi;
            counts = 10^6*counts./areas;

            warning('off','all')
            mkdir twoColourOutput
            warning('on','all')

            writecell({counts;edges},app.fileName('lamin RadialDistribution density (signal per um^2)'));
            writematrix(radDist,app.fileName('laminRadDist (um)'));
            standardisedDensities = normalize(counts,'range');
            writecell({standardisedDensities;edges},app.fileName('Lamin normalised densities'));

            %% geometric data
            area = app.area;
            eccentricity = sqrt(1-(min(ellipse.a,ellipse.b)/max(ellipse.a,ellipse.b))^2);
            majorAxis = max(ellipse.a,ellipse.b);
            minorAxis = min(ellipse.a,ellipse.b);
            
            % Estimate circumference
            a = ellipse.a;
            b = ellipse.b;
            h = (a-b)^2/(a+b)^2;
            circumference = pi*(a+b)*(1+3*h/(10+sqrt(4-3*h)));
            geometricData = {"Major Axis", "Minor Axis", "Area", "Circumference","Eccentricity";...
                majorAxis, minorAxis, area, circumference, eccentricity};
            writecell(geometricData,app.fileName('Geometric Properties'));


        end

        % Button pushed function: GoButton
        function go(app, event)
            axes = app.UIAxes;
            % DBSCAN preprocessing
            data = app.laminData;
            app.precompDist = pdist2(data(:,1:2),data(:,1:2));
            
            app.SelectotherstuffButton.Enable = 'on';
            app.SelectlaminButton.Enable = 'on';
            app.MinimumNeighboursEditField.Enable = 'on';
            app.MinimumNieghboursSlider.Enable = 'on';
            app.NeighbourhoodEditField.Enable = 'on';
            app.NeighbourhoodSlider.Enable = 'on';
            app.ConvexhullCheckBox.Enable = 'on';
            app.EllipseCheckBox.Enable = 'on';
            app.DistanceFromEdgeButton.Enable = 'on';
            app.ExportButton.Enable = 'on';
            
            app.gone = true;
            axes.XGrid = 'off';
            axes.YGrid = 'off';
            axes.XMinorGrid = 'off';
            axes.YMinorGrid = 'off';
            
            app.generate();
        end

        % Value changed function: ThresholdEditField, xmaxEditField, 
        % ...and 3 other components
        function threshold(app, event)
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            thresholdValue = app.ThresholdEditField.Value;
            rawData = app.rawLaminData;
            
            
            % Thresholding
            thresholdIndex = rawData(:,4) >= thresholdValue & rawData(:,1) >= xmin & rawData(:,1) <= xmax & rawData(:,2) >= ymin & rawData(:,2) <= ymax;
            app.thresholdIndex = thresholdIndex;
            
            data = rawData(thresholdIndex,:);
            app.laminData = data;
            axes = app.UIAxes;
            cla(axes);

            scatterPlot = scatter(axes, rawData(:,1), rawData(:,2), app.DotSizeEditField.Value, thresholdIndex);
            
            axes.XGrid = 'on';
            axes.YGrid = 'on';
            axes.XMinorGrid = 'on';
            axes.YMinorGrid = 'on';
            
            app.MinimumNeighboursEditField.Enable = 'off';
            app.MinimumNieghboursSlider.Enable = 'off';
            app.NeighbourhoodEditField.Enable = 'off';
            app.NeighbourhoodSlider.Enable = 'off';
            app.ConvexhullCheckBox.Enable = 'off';
            app.EllipseCheckBox.Enable = 'off';
            app.ExportButton.Enable = 'off';
            
            app.gone = false;
            rawSize = size(rawData,1);
            dataSize = size(data,1);
            app.LocalisationsEditField.Value = sprintf('%d/%d',dataSize,rawSize);
            
        end

        % Value changed function: HistoneThresholdEditField
        function histoneThreshold(app, event)
            index = app.rawOtherData(:,4) > app.HistoneThresholdEditField.Value;
            app.otherData = app.rawOtherData(index,:);
            rawSize = size(app.rawOtherData,1);
            dataSize = size(app.otherData,1);
            app.LocalisationsEditField.Value = sprintf('%d/%d',dataSize,rawSize);
            
            app.normalisedRadialDistances = radialDist(app.otherData,app.ellipse);
            app.generatePlot();
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {553, 553};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Two Colour Radial Distribution';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MinimumNeighboursEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumNeighboursEditFieldLabel.Text = 'Minimum Neighbours';
            app.MinimumNeighboursEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinimumNeighboursEditField.ValueChangedFcn = createCallbackFcn(app, @minimumNieghboursEditFieldFunc, true);
            app.MinimumNeighboursEditField.Enable = 'off';
            app.MinimumNeighboursEditField.Value = 30;
            app.NeighbourhoodEditFieldLabel = uilabel(app.LeftPanel);
            app.NeighbourhoodEditFieldLabel.Text = 'Neighbourhood';
            app.NeighbourhoodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NeighbourhoodEditField.ValueChangedFcn = createCallbackFcn(app, @neighbourhoodEditFieldFunc, true);
            app.NeighbourhoodEditField.Enable = 'off';
            app.NeighbourhoodEditField.Value = 400;
            app.SelectlaminButton = uibutton(app.LeftPanel, 'push');
            app.SelectlaminButton.ButtonPushedFcn = createCallbackFcn(app, @selectLaminData, true);
            app.SelectlaminButton.Text = 'Select lamin';
            app.DotSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.DotSizeEditFieldLabel.Text = 'Dot Size';
            app.DotSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DotSizeEditField.ValueChangedFcn = createCallbackFcn(app, @dotSize, true);
            app.DotSizeEditField.Enable = 'off';
            app.DotSizeEditField.Value = 2;
            app.NeighbourhoodSlider = uislider(app.LeftPanel);
            app.NeighbourhoodSlider.Limits = [10 1000];
            app.NeighbourhoodSlider.MajorTicks = [0 200 400 600 800 1000];
            app.NeighbourhoodSlider.ValueChangedFcn = createCallbackFcn(app, @neighbourhoodSliderFunc, true);
            app.NeighbourhoodSlider.MinorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.NeighbourhoodSlider.Enable = 'off';
            app.NeighbourhoodSlider.Value = 400;
            app.MinimumNieghboursSlider = uislider(app.LeftPanel);
            app.MinimumNieghboursSlider.Limits = [10 150];
            app.MinimumNieghboursSlider.MajorTicks = [0 30 60 90 120 150];
            app.MinimumNieghboursSlider.ValueChangedFcn = createCallbackFcn(app, @minimumNeighboursSliderFunc, true);
            app.MinimumNieghboursSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150];
            app.MinimumNieghboursSlider.Enable = 'off';
            app.MinimumNieghboursSlider.Value = 30;
            app.DotSizeSlider = uislider(app.LeftPanel);
            app.DotSizeSlider.Limits = [0.1 4];
            app.DotSizeSlider.MajorTicks = [0 1 2 3 4];
            app.DotSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DotSizeSliderValueChanged, true);
            app.DotSizeSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4];
            app.DotSizeSlider.Enable = 'off';
            app.DotSizeSlider.Value = 2;
            app.ConvexhullCheckBox = uicheckbox(app.LeftPanel);
            app.ConvexhullCheckBox.ValueChangedFcn = createCallbackFcn(app, @generate2, true);
            app.ConvexhullCheckBox.Enable = 'off';
            app.ConvexhullCheckBox.Text = 'Convex hull';
            app.EllipseCheckBox = uicheckbox(app.LeftPanel);
            app.EllipseCheckBox.ValueChangedFcn = createCallbackFcn(app, @generate2, true);
            app.EllipseCheckBox.Enable = 'off';
            app.EllipseCheckBox.Text = 'Ellipse';
            app.DistanceFromEdgeButton = uibutton(app.LeftPanel, 'push');
            app.DistanceFromEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @distanceFromEdge, true);
            app.DistanceFromEdgeButton.Enable = 'off';
            app.DistanceFromEdgeButton.Text = 'Distance from edge';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @export, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.SelectotherstuffButton = uibutton(app.LeftPanel, 'push');
            app.SelectotherstuffButton.ButtonPushedFcn = createCallbackFcn(app, @selectOtherData, true);
            app.SelectotherstuffButton.Enable = 'off';
            app.SelectotherstuffButton.Text = 'Select other stuff';
            app.LocalisationsEditFieldLabel = uilabel(app.LeftPanel);
            app.LocalisationsEditFieldLabel.Text = 'Localisations';
            app.LocalisationsEditField = uieditfield(app.LeftPanel, 'text');
            app.LocalisationsEditField.Editable = 'off';
            app.HistoneThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.HistoneThresholdEditFieldLabel.Text = 'Histone Threshold';
            app.HistoneThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HistoneThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @histoneThreshold, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'Normalised Distance')
            ylabel(app.UIAxes2, 'Frequency')
            app.Areaum2EditFieldLabel = uilabel(app.RightPanel);
            app.Areaum2EditFieldLabel.Text = 'Area (um^2)';
            app.Areaum2EditField = uieditfield(app.RightPanel, 'numeric');
            app.Areaum2EditField.Editable = 'off';
            app.GoButton = uibutton(app.RightPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @go, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.xminEditFieldLabel = uilabel(app.RightPanel);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.RightPanel, 'numeric');
            app.xminEditField.ValueDisplayFormat = '%11.8g';
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.xminEditField.Enable = 'off';
            app.xmaxEditFieldLabel = uilabel(app.RightPanel);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.xmaxEditField.ValueDisplayFormat = '%11.8g';
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.xmaxEditField.Enable = 'off';
            app.yminEditFieldLabel = uilabel(app.RightPanel);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.RightPanel, 'numeric');
            app.yminEditField.ValueDisplayFormat = '%11.8g';
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.yminEditField.Enable = 'off';
            app.ymaxEditFieldLabel = uilabel(app.RightPanel);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.ymaxEditField.ValueDisplayFormat = '%11.8g';
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.ymaxEditField.Enable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.RightPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.RightPanel, 'numeric');
            app.ThresholdEditField.ValueDisplayFormat = '%11.8g';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.ThresholdEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = twoColour_v8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end